classdef FormPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        updateButton            matlab.ui.control.Button
        chooseButton            matlab.ui.control.Button
        FilePathEditField       matlab.ui.control.EditField
        FilePathEditFieldLabel  matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        dTab                    matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        dTab_2                  matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file_name
        data
        tab = 1;
    end
    
    methods (Access = private)
        
        function update_figure(app)
            if app.tab == 1
                plot(app.UIAxes, app.data(:, 2), app.data(:, 5), ...
                     app.data(:, 3), app.data(:, 6), ...
                     app.data(:, 4), app.data(:, 7));
            elseif app.tab == 2
                plot3(app.UIAxes2, app.data(:, 2), app.data(:, 5), app.data(:, 8), ...
                      app.data(:, 3), app.data(:, 6), app.data(:, 9), ...
                      app.data(:, 4), app.data(:, 7), app.data(:, 10));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            [fileName, filePath] = uigetfile({'*.*', 'All Files (*.*)'});
            if ~isequal(fileName, 0)
                app.file_name = fullfile(filePath, fileName);
                app.FilePathEditField.Value = app.file_name;
                app.data = importdata(app.file_name).data;
                update_figure(app);
            end
        end

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
            app.file_name = app.FilePathEditField.Value;
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            app.data = importdata(app.file_name).data;
            update_figure(app);
        end

        % Button down function: dTab_2
        function dTab_2ButtonDown(app, event)
            app.tab = 2;
            update_figure(app);
        end

        % Button down function: dTab
        function dTabButtonDown(app, event)
            app.tab = 1;
            update_figure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.dTab = uitab(app.TabGroup);
            app.dTab.Title = '2d';
            app.dTab.ButtonDownFcn = createCallbackFcn(app, @dTabButtonDown, true);
            app.UIAxes = uiaxes(app.dTab);
            title(app.UIAxes, 'XOY')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.dTab_2 = uitab(app.TabGroup);
            app.dTab_2.Title = '3d';
            app.dTab_2.ButtonDownFcn = createCallbackFcn(app, @dTab_2ButtonDown, true);
            app.UIAxes2 = uiaxes(app.dTab_2);
            title(app.UIAxes2, 'XYZ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path:';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.FilePathEditField.Editable = 'off';
            app.chooseButton = uibutton(app.UIFigure, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Text = 'choose';
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FormPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end